classdef Final_Project < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        ResetDateLimiteButton         matlab.ui.control.Button
        LastDayDatePicker_2           matlab.ui.control.DatePicker
        LastDayDatePicker_2Label      matlab.ui.control.Label
        LimitYourDatasDateLabel       matlab.ui.control.Label
        FirstDayDatePicker            matlab.ui.control.DatePicker
        FirstDayDatePickerLabel       matlab.ui.control.Label
        SearchStateORRegionEditFieldLabel  matlab.ui.control.Label
        SearchStateORRegionEditField  matlab.ui.control.EditField
        SearchCountryEditFieldLabel   matlab.ui.control.Label
        SearchCountryEditField        matlab.ui.control.EditField
        AveragedofdaysSlider          matlab.ui.control.Slider
        AveragedofdaysSliderLabel     matlab.ui.control.Label
        StateorRegionListBox          matlab.ui.control.ListBox
        CountryListBox                matlab.ui.control.ListBox
        CountryLabel                  matlab.ui.control.Label
        StateorRegionListBoxLabel     matlab.ui.control.Label
        OptionsButtonGroup            matlab.ui.container.ButtonGroup
        DailyButton                   matlab.ui.control.RadioButton
        CumulativeButton              matlab.ui.control.RadioButton
        DataToPlotButtonGroup         matlab.ui.container.ButtonGroup
        BothButton                    matlab.ui.control.RadioButton
        DeathButton                   matlab.ui.control.RadioButton
        CasesButton                   matlab.ui.control.RadioButton
        UIAxes                        matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        date
        CountriesObj
        CountriesCell
        CurrentCountry
        StatesObj
        StatesCell
        CurrentStatesObj
        Value
        StateCumCases
        StateCumDeath
        DateTime
        DailyCases
        DailyDeath
        PartialTimePoints
        AverageCumCases
        AverageCumDeath
        AverageDayCases
        AverageDayDeath
        SearchCountry
        SearchState
    end
    
    methods (Access = private)
        function SetStates(app,Country)
            app.StatesCell = {};
            c = ismember(app.CountriesCell,Country);
            app.CurrentCountry = app.CountriesObj{c};
            head = app.CurrentCountry.Head;
            for i = 1:app.CountriesObj{c}.Length
                app.CurrentStatesObj{i} = head;
                app.StatesCell{i} = head.Name;
                head = head.Next;
            end
            app.StateorRegionListBox.Items = unique(app.StatesCell,'stable');
            app.StateorRegionListBox.Value = app.StateorRegionListBox.Items(1);  % To Fix when user enter wrong word in search
            app.ValuesofStates(app.StatesCell{1});
        end
        
        function AfterSearchCountry(app)
            index = find(contains(lower(app.CountriesCell),lower(app.SearchCountry)));
            app.CountryListBox.Items = unique(app.CountriesCell(index),'stable');
            if isempty(app.SearchCountryEditField.Value)  % To Fix when user enter wrong word in search
                app.CountryListBox.Value = app.CountryListBox.Items(1);
            end
            if ~isempty(app.CountryListBox.Items)  
                app.CountryListBox.Value = app.CountryListBox.Items(1); % To Fix when user enter wrong word in search
                app.SetStates(app.CountriesCell(index(1)));       
            else  % To Fix when user enter wrong word in search
                app.StateorRegionListBox.Items = cell(1,0);
            end
            
        end
        
        function AfterSearchState(app)
            index = find(contains(lower(app.StatesCell),lower(app.SearchState)));
            app.StateorRegionListBox.Items = unique(app.StatesCell(index),'stable');
            if isempty(app.SearchStateORRegionEditField.Value)  % To Fix when user enter wrong word in search
                app.StateorRegionListBox.Value = app.StateorRegionListBox.Items(1);
            end
            if ~isempty(app.StateorRegionListBox.Items)  
                app.StateorRegionListBox.Value = app.StateorRegionListBox.Items(1); % To Fix when user enter wrong word in search
                app.ValuesofStates(app.StatesCell(index(1)));
            end
        end
        
        function ValuesofStates(app,State)
            c = ismember(app.StatesCell,State);
            app.StateCumCases = app.CurrentStatesObj{c}.Cases;
            app.StateCumDeath = app.CurrentStatesObj{c}.Death;
            
            app.CalcDaily;
            app.CalcAverage;
            app.MyPlot;
        end
        
        function MyPlot(app)
            app.UIAxes.reset; % Reset Axes Graph
            % Check DataToPlot ButtonGroup & Options ButtonGroup
            if app.CumulativeButton.Value
                label = "Cumulative Number of Covid-19 ";
                if app.CasesButton.Value
                    label = label + "Cases in ";
                    bar(app.UIAxes,app.PartialTimePoints, app.AverageCumCases,'blue');
                elseif app.DeathButton.Value
                    label = label + "Deaths in ";
                    plot(app.UIAxes,app.PartialTimePoints, app.AverageCumDeath, 'r'); %put average instead of StateCumDeath
                 elseif app.BothButton.Value
                    label = label + "Cases and Deaths in ";
                    yyaxis(app.UIAxes,"left");
                    bar(app.UIAxes,app.PartialTimePoints, app.AverageCumCases,'b');
                    hold(app.UIAxes,'on');
                    yyaxis(app.UIAxes,"right")
                    plot(app.UIAxes,app.PartialTimePoints, app.AverageCumDeath, 'r');
                    app.UIAxes.YColor = 'r';
                end
            elseif app.DailyButton.Value
                label = "Daily Number of Covid-19 ";
                if app.CasesButton.Value
                    label = label + "Cases in ";
                    bar(app.UIAxes,app.PartialTimePoints, app.AverageDayCases,'blue');
                elseif app.DeathButton.Value
                    label = label + "Deaths in ";
                    plot(app.UIAxes,app.PartialTimePoints, app.AverageDayDeath, 'r'); %put average instead of StateCumDeath
                 elseif app.BothButton.Value
                    label = label + "Cases and Deaths in ";
                    yyaxis(app.UIAxes,"left");
                    bar(app.UIAxes,app.PartialTimePoints, app.AverageDayCases,'b');
                    hold(app.UIAxes,'on');
                    yyaxis(app.UIAxes,"right")
                    plot(app.UIAxes,app.PartialTimePoints, app.AverageDayDeath, 'r');
                    app.UIAxes.YColor = 'r';
                end
            end
            % Add Country, States and Average to Label
            label = label + app.CountryListBox.Value + "( " + app.StateorRegionListBox.Value + " ) ";
            if app.AveragedofdaysSlider.Value > 1
                label = label + " ( "+ app.AveragedofdaysSlider.Value + "-Day Mean )";
            end
            % Make Axis Ready
            xlim(app.UIAxes,[datenum(app.FirstDayDatePicker.Value), datenum(app.LastDayDatePicker_2.Value)]); xlim(app.UIAxes,'manual');
            grid(app.UIAxes,'on');
            datetick(app.UIAxes,'x','mm/dd',"keeplimits");
            if app.CasesButton.Value || app.DeathButton.Value
                app.UIAxes.YAxis.Exponent = 0; app.UIAxes.YAxis.TickLabelFormat = '%.0f';
            elseif app.BothButton.Value
                app.UIAxes.YAxis(1).Exponent = 0; app.UIAxes.YAxis(1).TickLabelFormat = '%.0f';
                app.UIAxes.YAxis(2).Exponent = 0; app.UIAxes.YAxis(2).TickLabelFormat = '%.0f';
            end

            title(app.UIAxes,label,"HorizontalAlignment","center");
            hold(app.UIAxes,'off');
        end
        
        function CalcAverage(app)
            % Calc Cumulative Average 
            app.AverageCumCases = movmean(app.StateCumCases, app.AveragedofdaysSlider.Value,'Endpoints','discard');
            app.AverageCumDeath = movmean(app.StateCumDeath, app.AveragedofdaysSlider.Value,'Endpoints','discard');
            % Calc Daily Average 
            app.AverageDayCases = movmean(app.DailyCases, app.AveragedofdaysSlider.Value,'Endpoints','discard');
            app.AverageDayDeath = movmean(app.DailyDeath, app.AveragedofdaysSlider.Value,'Endpoints','discard');
            % Calc the Date Range
            inside_range = (app.AveragedofdaysSlider.Value):length(app.DateTime);
            app.PartialTimePoints = app.DateTime(inside_range);
            % Plot
            app.MyPlot;
        end
        
        function CalcDaily(app)
            app.DailyCases = app.StateCumCases - circshift(app.StateCumCases,1);
            app.DailyDeath = app.StateCumDeath - circshift(app.StateCumDeath,1);
            app.DailyCases(1) = app.StateCumCases(1);
            app.DailyDeath(1) = app.StateCumDeath(1);
            chk1 = logical(app.DailyCases < 0);
            chk2 = logical(app.DailyDeath < 0);
            app.DailyCases(chk1) = 0;
            app.DailyDeath(chk2) = 0;
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            load covid_data.mat covid_data;
            x = 0;
            y = 1;
            for i = 2:size(covid_data,1) % to fix the size of app.CountriesObj
                if ~isequal(covid_data(i), covid_data(i-1))
                    x = x + 1;
                end
            end
            % Date
            app.date = covid_data(1,3:end);
            First = datenum(app.date{1}); Last = datenum(app.date{end});
            app.DateTime = linspace(First,Last,length(app.date));
            % set the size
            app.CountriesObj = cell(1,x);
            app.StatesObj = cell(1,size(covid_data,1));
            app.CountriesObj{1} = DList({'Global'},1);
            app.StatesObj{1,1} = LinkedNode({'All'});
            app.CountriesObj{1}.insert(app.StatesObj{1,1})
            app.CountriesObj{1}.Head.Cases = zeros(1,size(covid_data,2)-2);
            app.CountriesObj{1}.Head.Death = zeros(1,size(covid_data,2)-2);
            app.CountriesCell = cell(1,x+1);
            app.CountriesCell(1) = {'Global'};
            
            for i = 2:size(covid_data,1)
                if ~isequal(covid_data(i), covid_data(i-1))
                    y = y+1;
                    app.CountriesObj{1,y} = DList(covid_data(i,1),i);
                    app.CountriesCell(y) = app.CountriesObj{1,y}.Name;
                end
                % Cases & Death
                app.StatesObj{1,i} = LinkedNode(covid_data(i,2));
                app.StatesObj{1,i}.Cases = zeros(1,size(covid_data,2)-2); 
                app.StatesObj{1,i}.Death = zeros(1,size(covid_data,2)-2); 
                for ii = 3:size(covid_data,2)
                    app.StatesObj{1,i}.Cases(1,ii-2) = covid_data{i,ii}(1);
                    app.StatesObj{1,i}.Death(1,ii-2) = covid_data{i,ii}(2);
                    if isempty(covid_data{i,2})
                        app.CountriesObj{1}.Head.Cases(ii-2) = app.CountriesObj{1}.Head.Cases(ii-2) + covid_data{i,ii}(1);
                        app.CountriesObj{1}.Head.Death(ii-2) = app.CountriesObj{1}.Head.Death(ii-2) + covid_data{i,ii}(2);            
                    end
                end
                % Insert states to Country
                app.CountriesObj{1,y}.insert(app.StatesObj{1,i})
            end
            % Set ListBoxes
            app.CountryListBox.Items = unique(app.CountriesCell,'stable');
            app.CountryListBox.Value = {'Global'};
            app.SetStates(app.CountryListBox.Value);

        end

        % Value changed function: CountryListBox
        function CountryListBoxValueChanged(app, event)
            app.SetStates(app.CountryListBox.Value);
        end

        % Value changed function: StateorRegionListBox
        function StateorRegionListBoxValueChanged(app, event)
            app.ValuesofStates(app.StateorRegionListBox.Value)
        end

        % Value changed function: AveragedofdaysSlider
        function AveragedofdaysSliderValueChanged(app, event)
            app.AveragedofdaysSlider.Value = round(app.AveragedofdaysSlider.Value);
            app.CalcAverage();
        end

        % Selection changed function: DataToPlotButtonGroup
        function DataToPlotButtonGroupSelectionChanged(app, event)
            app.MyPlot();
        end

        % Selection changed function: OptionsButtonGroup
        function OptionsButtonGroupSelectionChanged(app, event)
            app.MyPlot();
        end

        % Value changed function: SearchCountryEditField
        function SearchCountryEditFieldValueChanged(app, event)
            app.SearchCountry = app.SearchCountryEditField.Value;
            app.AfterSearchCountry;
            app.SearchStateORRegionEditField.Value = '';
        end

        % Value changed function: SearchStateORRegionEditField
        function SearchStateORRegionEditFieldValueChanged(app, event)
            app.SearchState = app.SearchStateORRegionEditField.Value;
            app.AfterSearchState;
        end

        % Value changed function: FirstDayDatePicker
        function FirstDayDatePickerValueChanged(app, event)
            app.MyPlot;
            app.LastDayDatePicker_2.Limits = [(app.FirstDayDatePicker.Value+1) datetime([2021 2 1])];
        end

        % Value changed function: LastDayDatePicker_2
        function LastDayDatePicker_2ValueChanged(app, event)
            app.MyPlot;
            app.FirstDayDatePicker.Limits = [datetime([2020 1 1]) (app.LastDayDatePicker_2.Value-1)];
        end

        % Callback function
        function EliminatetheFlowinEdgesDuetoAverageCheckBoxValueChanged(app, event)
            app.CalcAverage;
        end

        % Button pushed function: ResetDateLimiteButton
        function ResetDateLimiteButtonPushed(app, event)
            app.FirstDayDatePicker.Value = app.FirstDayDatePicker.Limits(1);
            app.LastDayDatePicker_2.Value = app.LastDayDatePicker_2.Limits(end);
            app.FirstDayDatePicker.Limits = [datetime([2020 1 1]) (app.LastDayDatePicker_2.Value-1)];
            app.LastDayDatePicker_2.Limits = [(app.FirstDayDatePicker.Value+1) datetime([2021 2 1])];
            app.MyPlot;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            zlabel(app.UIAxes, 'Z')
            app.DataToPlotButtonGroup = uibuttongroup(app.UIFigure);
            app.DataToPlotButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @DataToPlotButtonGroupSelectionChanged, true);
            app.DataToPlotButtonGroup.Title = 'Data To Plot';
            app.CasesButton = uiradiobutton(app.DataToPlotButtonGroup);
            app.CasesButton.Text = 'Cases';
            app.CasesButton.Value = true;
            app.DeathButton = uiradiobutton(app.DataToPlotButtonGroup);
            app.DeathButton.Text = 'Death';
            app.BothButton = uiradiobutton(app.DataToPlotButtonGroup);
            app.BothButton.Text = 'Both';
            app.OptionsButtonGroup = uibuttongroup(app.UIFigure);
            app.OptionsButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @OptionsButtonGroupSelectionChanged, true);
            app.OptionsButtonGroup.Title = 'Options';
            app.CumulativeButton = uiradiobutton(app.OptionsButtonGroup);
            app.CumulativeButton.Text = 'Cumulative';
            app.CumulativeButton.Value = true;
            app.DailyButton = uiradiobutton(app.OptionsButtonGroup);
            app.DailyButton.Text = 'Daily';
            app.StateorRegionListBoxLabel = uilabel(app.UIFigure);
            app.StateorRegionListBoxLabel.Text = 'State or Region:  ';
            app.CountryLabel = uilabel(app.UIFigure);
            app.CountryLabel.Text = 'Country:  ';
            app.CountryListBox = uilistbox(app.UIFigure);
            app.CountryListBox.Items = {'Item 1', 'Item 2'};
            app.CountryListBox.ValueChangedFcn = createCallbackFcn(app, @CountryListBoxValueChanged, true);
            app.StateorRegionListBox = uilistbox(app.UIFigure);
            app.StateorRegionListBox.Items = {'Item 1', 'Item 2', ''};
            app.StateorRegionListBox.ValueChangedFcn = createCallbackFcn(app, @StateorRegionListBoxValueChanged, true);
            app.AveragedofdaysSliderLabel = uilabel(app.UIFigure);
            app.AveragedofdaysSliderLabel.Text = 'Averaged of days:';
            app.AveragedofdaysSlider = uislider(app.UIFigure);
            app.AveragedofdaysSlider.Limits = [1 15];
            app.AveragedofdaysSlider.MajorTicks = [1 3 5 7 9 11 13 15];
            app.AveragedofdaysSlider.ValueChangedFcn = createCallbackFcn(app, @AveragedofdaysSliderValueChanged, true);
            app.AveragedofdaysSlider.Value = 1;
            app.SearchCountryEditField = uieditfield(app.UIFigure, 'text');
            app.SearchCountryEditField.ValueChangedFcn = createCallbackFcn(app, @SearchCountryEditFieldValueChanged, true);
            app.SearchCountryEditField.Placeholder = 'Search For Country...';
            app.SearchCountryEditFieldLabel = uilabel(app.UIFigure);
            app.SearchCountryEditFieldLabel.Text = 'Search Country';
            app.SearchStateORRegionEditField = uieditfield(app.UIFigure, 'text');
            app.SearchStateORRegionEditField.ValueChangedFcn = createCallbackFcn(app, @SearchStateORRegionEditFieldValueChanged, true);
            app.SearchStateORRegionEditField.Placeholder = 'Enter The States...';
            app.SearchStateORRegionEditFieldLabel = uilabel(app.UIFigure);
            app.SearchStateORRegionEditFieldLabel.Text = 'Search State OR Region';
            app.FirstDayDatePickerLabel = uilabel(app.UIFigure);
            app.FirstDayDatePickerLabel.Text = 'First Day';
            app.FirstDayDatePicker = uidatepicker(app.UIFigure);
            app.FirstDayDatePicker.Limits = [datetime([2020 1 1]) datetime([2021 1 31])];
            app.FirstDayDatePicker.DisplayFormat = 'dd-MM-uuuu';
            app.FirstDayDatePicker.ValueChangedFcn = createCallbackFcn(app, @FirstDayDatePickerValueChanged, true);
            app.FirstDayDatePicker.Value = datetime([2020 1 1]);
            app.LimitYourDatasDateLabel = uilabel(app.UIFigure);
            app.LimitYourDatasDateLabel.Text = 'Limit Your Data''s Date :';
            app.LastDayDatePicker_2Label = uilabel(app.UIFigure);
            app.LastDayDatePicker_2Label.Text = 'Last Day';
            app.LastDayDatePicker_2 = uidatepicker(app.UIFigure);
            app.LastDayDatePicker_2.Limits = [datetime([2020 1 2]) datetime([2021 2 1])];
            app.LastDayDatePicker_2.DisplayFormat = 'dd-MM-uuuu';
            app.LastDayDatePicker_2.ValueChangedFcn = createCallbackFcn(app, @LastDayDatePicker_2ValueChanged, true);
            app.LastDayDatePicker_2.Value = datetime([2021 2 1]);
            app.ResetDateLimiteButton = uibutton(app.UIFigure, 'push');
            app.ResetDateLimiteButton.ButtonPushedFcn = createCallbackFcn(app, @ResetDateLimiteButtonPushed, true);
            app.ResetDateLimiteButton.Text = 'Reset Date Limite';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Final_Project

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end